classdef appactivation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StatusText                  matlab.ui.control.Label
        EmailText                   matlab.ui.control.Label
        LicenseText                 matlab.ui.control.Label
        ActivateButton              matlab.ui.control.Button
        EmailAddressEditField       matlab.ui.control.EditField
        EmailAddressEditFieldLabel  matlab.ui.control.Label
        LicenseKeyEditField         matlab.ui.control.EditField
        LicenseKeyEditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = private)
        AppName;
        UserName;
        RepoName;
        Token;
        AppIcon;
        IsLicenseFormatValid = false;
        IsEmailFormatValid = false;
        GreenHexCode = '#64d413';
    end

    methods (Access = private)

        function Validity = checkLicenseFormatValidity(app, EnteredLicense)
            LicensePattern = alphanumericsPattern(4) + "-" + alphanumericsPattern(4) + "-" + alphanumericsPattern(4) + "-" + alphanumericsPattern(4);
            Validity = matches(EnteredLicense, LicensePattern);
        end

        function Validity = checkEmailFormatValidity(app, EnteredEmail)
            Validity = contains(EnteredEmail, '@');
        end

        function enableActivateButton(app)
            if app.IsLicenseFormatValid && app.IsEmailFormatValid
                app.ActivateButton.Enable = 'on';
            else
                app.ActivateButton.Enable = 'off';
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AppName, UserName, RepoName, Token, AppIcon)
            arguments
                app
                AppName
                UserName
                RepoName
                Token
                AppIcon = ''
            end
            app.AppName = AppName;
            app.UserName = UserName;
            app.RepoName = RepoName;
            app.Token = Token;
            app.UIFigure.Name = [app.AppName, ' License Activation'];
            if ~isempty(app.AppIcon)
                app.UIFigure.Icon = AppIcon;
            end
        end

        % Value changed function: LicenseKeyEditField
        function LicenseKeyEditFieldValueChanged(app, event)
            app.IsLicenseFormatValid = checkLicenseFormatValidity(app, app.LicenseKeyEditField.Value);
            if app.IsLicenseFormatValid
                set(app.LicenseText, Text = '');
            else
                set(app.LicenseText, Text = 'License must be in XXXX-XXXX-XXXX-XXXX format', FontColor = 'red');
            end
            if isempty(app.LicenseKeyEditField.Value)
                set(app.LicenseText, Text = '');
            end
            app.LicenseKeyEditField.Value = upper(app.LicenseKeyEditField.Value);
            enableActivateButton(app);
        end

        % Value changed function: EmailAddressEditField
        function EmailAddressEditFieldValueChanged(app, event)
            app.IsEmailFormatValid = checkEmailFormatValidity(app, app.EmailAddressEditField.Value);
            if app.IsEmailFormatValid
                set(app.EmailText, Text = '');
            else
                set(app.EmailText, Text = 'Please enter a valid email address', FontColor = 'red');
            end
            if isempty(app.EmailAddressEditField.Value)
                set(app.EmailText, Text = '');
            end
            enableActivateButton(app);
        end

        % Button pushed function: ActivateButton
        function ActivateButtonPushed(app, event)
            [app.LicenseText.Text, app.EmailText.Text, app.StatusText.Text] = deal('');
            try LicenseFile = githubGet(app.UserName, app.RepoName, 'license-database.csv', 'save', Token = app.Token);
                set(app.StatusText, Text = 'Activating license...', FontColor = 'k');
                drawnow;
            catch
                set(app.StatusText, Text = 'Internet connection failure', FontColor = 'r');
                return;
            end
            Lic = readtable(LicenseFile);
            delete(LicenseFile);
            Lic.LicenseKey = string(Lic.LicenseKey);
            Lic.ValidUntil = datetime(Lic.ValidUntil);
            Lic.UserEmail = string(Lic.UserEmail);
            Lic.DeviceID = string(Lic.DeviceID);

            EnteredLicense = string(app.LicenseKeyEditField.Value);
            MatchedLicenseIdx = find(Lic.LicenseKey == EnteredLicense);
            if isempty(MatchedLicenseIdx)
                set(app.LicenseText, Text = 'License key is not valid', FontColor = 'r');
                return;
            end

            ValidUntil = Lic.ValidUntil(MatchedLicenseIdx);
            if Lic.ValidUntil(MatchedLicenseIdx) < datetime
                set(app.LicenseText, Text = sprintf('License key has expired since %s', ValidUntil), FontColor = 'r');
                return;
            end
            set(app.LicenseText, Text = sprintf('License key is valid until %s', ValidUntil), FontColor = app.GreenHexCode);

            Email = Lic.UserEmail(MatchedLicenseIdx);
            EnteredEmail = string(app.EmailAddressEditField.Value);
            if Email ~= EnteredEmail
                set(app.EmailText, Text = 'The license is assigned to a different email address', FontColor = 'r');
                return;
            end
            set(app.EmailText, Text = 'The license matches with the email address', FontColor = app.GreenHexCode);

            DeviceID = licenseKeygen;
            writematrix(DeviceID, 'device-id.txt') 
            Lic.DeviceID(MatchedLicenseIdx) = string(DeviceID);
            writetable(Lic, 'license-database.csv');
            githubPut(app.UserName, app.RepoName, 'license-database.csv', 'license-database.csv', ...
                app.Token, CommitMessage = sprintf('Device %s activated', DeviceID));
            delete('license-database.csv');
            set(app.StatusText, Text = 'License is activated!', FontColor = app.GreenHexCode);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LicenseKeyEditFieldLabel = uilabel(app.UIFigure);
            app.LicenseKeyEditFieldLabel.Text = 'License Key';
            app.LicenseKeyEditField = uieditfield(app.UIFigure, 'text');
            app.LicenseKeyEditField.ValueChangedFcn = createCallbackFcn(app, @LicenseKeyEditFieldValueChanged, true);
            app.EmailAddressEditFieldLabel = uilabel(app.UIFigure);
            app.EmailAddressEditFieldLabel.Text = 'Email Address';
            app.EmailAddressEditField = uieditfield(app.UIFigure, 'text');
            app.EmailAddressEditField.ValueChangedFcn = createCallbackFcn(app, @EmailAddressEditFieldValueChanged, true);
            app.ActivateButton = uibutton(app.UIFigure, 'push');
            app.ActivateButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateButtonPushed, true);
            app.ActivateButton.Enable = 'off';
            app.ActivateButton.Text = 'Activate';
            app.LicenseText = uilabel(app.UIFigure);
            app.LicenseText.Text = '';
            app.EmailText = uilabel(app.UIFigure);
            app.EmailText.Text = '';
            app.StatusText = uilabel(app.UIFigure);
            app.StatusText.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appactivation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end